classdef audio_equalizer_final_ver2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IntrotoSignalsSystemsEqualizerUIFigure  matlab.ui.Figure
        PresetPanel              matlab.ui.container.Panel
        FlatButton               matlab.ui.control.Button
        BassBoosterButton        matlab.ui.control.Button
        TechnoButton             matlab.ui.control.Button
        RockButton               matlab.ui.control.Button
        PopButton                matlab.ui.control.Button
        MonitorPanel             matlab.ui.container.Panel
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        ControlPanel             matlab.ui.container.Panel
        SeekTime                 matlab.ui.control.Label
        PlayButton_2             matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        PauseButton              matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        VolumeSlider             matlab.ui.control.Slider
        VolumeSliderLabel        matlab.ui.control.Label
        SeekSlider               matlab.ui.control.Slider
        SeekSliderLabel          matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        StartEQButton            matlab.ui.control.Button
        FileSoundEditField       matlab.ui.control.EditField
        FileSoundEditFieldLabel  matlab.ui.control.Label
        BrowseButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Player % Description
        audio_length
        File
        t
        Fs
        output
        y
        N
        fileReader
        deviceWriter
        equalizer
        cn =1
        filepath
        gains = [0,10,-10,5,-5,2,-2,1,-1,0]
        eof
        filename
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [app.filename, pathname] = uigetfile({'*.mp3;*.wav'},'File Selector');
            app.File = strcat(pathname, app.filename);
            if (app.filename ~= 0)
                app.filepath = fullfile(pathname, app.filename);
                app.FileSoundEditField.Value = app.filepath;
                [audio_file, app.Fs] = audioread(app.filepath);
                app.audio_length = length(audio_file);
                app.t = 0:(1/app.Fs):(app.audio_length-1)*(1/app.Fs);
                %plot(app.UIAxes, app.t, audio_file);


                result = audio_file;
                N = length(result);
                Fs = 48000;
                %duration = length(result)/Fs;
                
                Ts = 1/Fs;
                t = 0:Ts:(N-1)*Ts;
                plot(app.UIAxes,t,result(:,1));
                hold (app.UIAxes,'on');
                plot(app.UIAxes,t,result(:,2));
                hold (app.UIAxes,'off');
                legend(app.UIAxes,'left','right');
                
                
                Length_audio=length(audio_file);
                df=app.Fs/Length_audio;
                frequency_audio=-app.Fs/2:df:app.Fs/2-df;
                FFT_audio_in=fftshift(fft(audio_file))/length(fft(audio_file));
               
                stem(app.UIAxes2,frequency_audio,abs(FFT_audio_in(:,1)),'Marker','none');
                hold (app.UIAxes2,'on');
                axis(app.UIAxes2, [0 app.Fs/2-df 0 inf]);
                stem(app.UIAxes2,frequency_audio,abs(FFT_audio_in(:,2)),'Marker','none');
                hold (app.UIAxes2,'off');
                legend(app.UIAxes2,'left','right');

                frameLength = 1024;
                app.fileReader = dsp.AudioFileReader(app.filepath,'SamplesPerFrame',frameLength);
                app.deviceWriter = audioDeviceWriter('SampleRate',app.fileReader.SampleRate);
                app.equalizer = graphicEQ('SampleRate',app.fileReader.SampleRate,'Gains',app.gains);

%                 disp(app.equalizer.Gains);


                [app.y, app.Fs] = audioread(app.File);
                app.Player = audioplayer(app.y, app.Fs);
                app.SeekSlider.Limits = [0 (app.audio_length-1)*(1/app.Fs)];
%                 disp(["SampleRate : ", app.fileReader.SampleRate]);
%                 disp(app.SeekSlider.Limits);
%                 disp(app.deviceWriter);
%                 disp(Length_audio);
%                 disp(app.audio_length);
%                 disp(app.y);
                
            end
        end

        % Button pushed function: StartEQButton
        function StartEQButtonPushed(app, event)
            if (app.filename ~= 0)
            visualize(app.equalizer)
            parameterTuner(app.equalizer)
            while (app.cn)
            %play(app.Player);
            audioIn = app.fileReader();
            audioOut = app.equalizer(audioIn);
            app.deviceWriter(audioOut);
            app.gains = app.equalizer.Gains;
%             disp(app.gains);
            drawnow limitrate % required to update parameter
            end
            release(app.deviceWriter)
            release(app.fileReader)
            release(app.equalizer)
            else
            f = msgbox('You did not open any file','Error','error');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if (app.filename ~= 0)
            if (app.cn ~= 1)
            app.cn = 1;
            while (app.cn)
            %play(app.Player);
            audioIn = app.fileReader();
            audioOut = app.equalizer(audioIn);
            app.deviceWriter(audioOut);
            app.gains = app.equalizer.Gains;
            drawnow limitrate % required to update parameter
%             disp(app.gains);
            end
            release(app.deviceWriter)
            release(app.fileReader)
            release(app.equalizer)
            end
            else
            f = msgbox('You did not open any file','Error','error');
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if (app.filename ~= 0)
            pause
            %pause(app.Player);
            else
            f = msgbox('You did not open any file','Error','error');
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if (app.filename ~= 0)
            app.cn = 0;
            %stop(app.Player);
            app.SeekSlider.Value = 0;
            app.SeekTime.Text = "0:00";
%             start = app.fileReader.SampleRate * value;
            app.fileReader = dsp.AudioFileReader(app.filepath);
%           disp(app.fileReader);
%             PlayButtonPushed(app,event);
            else
            f = msgbox('You did not open any file','Error','error');
            end
        end

        % Button pushed function: PopButton
        function PopButtonPushed(app, event)
            if (app.filename ~= 0)
%            StopButtonPushed(app,event);
           app.cn = 0;
%            release(app.equalizer);
%            app.equalizer = graphicEQ('SampleRate',app.fileReader.SampleRate,'Gains',[-10, -10, -10, -10, -10, -10, -10, -10, -10, -10]);
           app.equalizer.Gains = [-2.5, -2, 0, 2.5, 3.5, 3.5, 2.5, 0, -2, -2.5];
%            disp(app.equalizer.Gains);
           ResetButtonPushed(app,event);
            else
            f = msgbox('You did not open any file','Error','error');
            end
           
           
        end

        % Button pushed function: RockButton
        function RockButtonPushed(app, event)
            if (app.filename ~= 0)
%            StopButtonPushed(app,event);
           app.cn = 0;
           app.equalizer.Gains = [5, 4, 3, 2, -1, -1.5, 1, 2.5, 3.5, 4];
%            disp(app.equalizer.Gains);
           ResetButtonPushed(app,event);
            else
            f = msgbox('You did not open any file','Error','error');
            end
        end

        % Button pushed function: TechnoButton
        function TechnoButtonPushed(app, event)
            if (app.filename ~= 0)
%            StopButtonPushed(app,event);
           app.cn = 0;
           app.equalizer.Gains = [4.5, 4, 2, 0, -2.5, 2.5, 1, 1.5, 3, 4];
%            disp(app.equalizer.Gains);
           ResetButtonPushed(app,event);
            else
            f = msgbox('You did not open any file','Error','error');
            end
        end

        % Button pushed function: BassBoosterButton
        function BassBoosterButtonPushed(app, event)
            if (app.filename ~= 0)
%            StopButtonPushed(app,event);
           app.cn = 0;
           app.equalizer.Gains = [5.5, 4, 3, 2.5, 1.5, 0, 0, 0, 0, 0];
%            disp(app.equalizer.Gains);
           ResetButtonPushed(app,event);  
            else
            f = msgbox('You did not open any file','Error','error');
            end
        end

        % Button pushed function: FlatButton
        function FlatButtonPushed(app, event)
            if (app.filename ~= 0)
%            StopButtonPushed(app,event);
           app.cn = 0;
           app.equalizer.Gains = [0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
%            disp(app.equalizer.Gains);
           ResetButtonPushed(app,event); 
            else
            f = msgbox('You did not open any file','Error','error');
            end
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            if (app.filename ~= 0)
            app.cn = 1;
            while (app.cn)
            %resume(app.Player);
            audioIn = app.fileReader();
            audioOut = app.equalizer(audioIn);
            app.deviceWriter(audioOut);
            app.gains = app.equalizer.Gains;
            drawnow limitrate % required to update parameter
            end
            release(app.deviceWriter)
            release(app.fileReader)
            release(app.equalizer)
            else
            f = msgbox('You did not open any file','Error','error');
            end
        end

        % Value changing function: SeekSlider
        function SeekSliderValueChanging(app, event)
            s = seconds(event.Value);
            dur = string(duration(s, "Format", "mm:ss"));
            app.SeekTime.Text = dur;
        end

        % Value changed function: SeekSlider
        function SeekSliderValueChanged(app, event)
            if (app.filename ~= 0)
            app.cn = 0;
            value = app.SeekSlider.Value;
            start = app.fileReader.SampleRate * value;
            app.fileReader = dsp.AudioFileReader(app.filepath, "ReadRange",[uint64(start) app.audio_length]);
%           disp(app.fileReader);
            ResetButtonPushed(app,event);
            else
            f = msgbox('You did not open any file','Error','error');
            app.SeekSlider.Value = 0;
            app.SeekTime.Text = "0:00";
            end
        end

        % Value changing function: VolumeSlider
        function VolumeSliderValueChanging(app, event)

            
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            StopButtonPushed(app,event);
            value = app.VolumeSlider.Value;
            SoundVolume(value)
%             pause(app.Player);
%             NewStart = get(app.Player,'CurrentSample');
%             app.Player = audioplayer(app.y*Volume,app.Fs);
%             play(app.Player, NewStart);
%             app.fileReader = dsp.AudioFileReader(app.filepath, "ReadRange",[uint64(start) app.audio_length]);
%             app.deviceWriter = audioDeviceWriter('SampleRate',app.fileReader.SampleRate);
%             app.equalizer = graphicEQ('SampleRate',app.fileReader.SampleRate,'Gains', app.gains*Volume);
%             disp(app.fileReader);
            ResetButtonPushed(app,event);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IntrotoSignalsSystemsEqualizerUIFigure = uifigure('Visible', 'off');
            app.IntrotoSignalsSystemsEqualizerUIFigure.Name = 'Intro to Signals & Systems - Equalizer';
            app.Panel = uipanel(app.IntrotoSignalsSystemsEqualizerUIFigure);
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileSoundEditFieldLabel = uilabel(app.Panel);
            app.FileSoundEditFieldLabel.Text = 'File Sound :';
            app.FileSoundEditField = uieditfield(app.Panel, 'text');
            app.StartEQButton = uibutton(app.Panel, 'push');
            app.StartEQButton.ButtonPushedFcn = createCallbackFcn(app, @StartEQButtonPushed, true);
            app.StartEQButton.Text = 'Start EQ';
            app.ControlPanel = uipanel(app.IntrotoSignalsSystemsEqualizerUIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.SeekSliderLabel = uilabel(app.ControlPanel);
            app.SeekSliderLabel.Text = 'Seek :';
            app.SeekSlider = uislider(app.ControlPanel);
            app.SeekSlider.MajorTicks = [];
            app.SeekSlider.MajorTickLabels = {''};
            app.SeekSlider.ValueChangedFcn = createCallbackFcn(app, @SeekSliderValueChanged, true);
            app.SeekSlider.ValueChangingFcn = createCallbackFcn(app, @SeekSliderValueChanging, true);
            app.SeekSlider.MinorTicks = [];
            app.VolumeSliderLabel = uilabel(app.ControlPanel);
            app.VolumeSliderLabel.Text = 'Volume :';
            app.VolumeSlider = uislider(app.ControlPanel);
            app.VolumeSlider.Limits = [0 2];
            app.VolumeSlider.MajorTicks = [];
            app.VolumeSlider.MajorTickLabels = {''};
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @VolumeSliderValueChanging, true);
            app.VolumeSlider.MinorTicks = [];
            app.VolumeSlider.Value = 1;
            app.ResetButton = uibutton(app.ControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PauseButton = uibutton(app.ControlPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton_2 = uibutton(app.ControlPanel, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.SeekTime = uilabel(app.ControlPanel);
            app.SeekTime.Text = '0:00';
            app.MonitorPanel = uipanel(app.IntrotoSignalsSystemsEqualizerUIFigure);
            app.MonitorPanel.Title = 'Monitor';
            app.UIAxes = uiaxes(app.MonitorPanel);
            title(app.UIAxes, 'Original - Time Domain')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.MonitorPanel);
            title(app.UIAxes2, 'Original - Frequency Domain')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Magnitude')
            zlabel(app.UIAxes2, 'Z')
            app.PresetPanel = uipanel(app.IntrotoSignalsSystemsEqualizerUIFigure);
            app.PresetPanel.Title = 'Preset';
            app.PopButton = uibutton(app.PresetPanel, 'push');
            app.PopButton.ButtonPushedFcn = createCallbackFcn(app, @PopButtonPushed, true);
            app.PopButton.Text = 'Pop';
            app.RockButton = uibutton(app.PresetPanel, 'push');
            app.RockButton.ButtonPushedFcn = createCallbackFcn(app, @RockButtonPushed, true);
            app.RockButton.Text = 'Rock';
            app.TechnoButton = uibutton(app.PresetPanel, 'push');
            app.TechnoButton.ButtonPushedFcn = createCallbackFcn(app, @TechnoButtonPushed, true);
            app.TechnoButton.Text = 'Techno';
            app.BassBoosterButton = uibutton(app.PresetPanel, 'push');
            app.BassBoosterButton.ButtonPushedFcn = createCallbackFcn(app, @BassBoosterButtonPushed, true);
            app.BassBoosterButton.Text = 'Bass Booster';
            app.FlatButton = uibutton(app.PresetPanel, 'push');
            app.FlatButton.ButtonPushedFcn = createCallbackFcn(app, @FlatButtonPushed, true);
            app.FlatButton.Text = 'Flat';
            % Show the figure after all components are created
            app.IntrotoSignalsSystemsEqualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio_equalizer_final_ver2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IntrotoSignalsSystemsEqualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IntrotoSignalsSystemsEqualizerUIFigure)
        end
    end
end